classdef SendMail < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Image           matlab.ui.control.Image
        CONTACTUSLabel  matlab.ui.control.Label
        NameLabel       matlab.ui.control.Label
        EditField       matlab.ui.control.EditField
        EMailLabel      matlab.ui.control.Label
        EditField_2     matlab.ui.control.EditField
        ContextLabel    matlab.ui.control.Label
        EditField_3     matlab.ui.control.EditField
        SENDButton      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SENDButton
        function SENDButtonPushed(app, event)
            name=app.EditField.Value;
           mail=app.EditField_2.Value;
           message=app.EditField_3.Value;
           setpref('Internet','SMTP_Server','smtp.gmail.com');
           setpref('Internet','E_mail','serefcanhel@gmail.com')
           props=java.lang.System.getProperties;
           props.setProperty('mail.smtp.auth','true')
           props.setProperty('mail.smtp.starttls.enable','true')
           props.setProperty('mail.smtp.socketFactory.class','javax.net.ssl.SSLSocketFactory')
           props.setProperty('mail.smtp.socketFactory.port','465')
           setpref('Internet','SMTP_Username','serefcanhel@gmail.com')
           setpref('Internet','SMTP_Password','cancan2122646')
           send="From"+ name +" "+ mail +" "+ message;
           sendmail('serefcanhel@gmail.com','MATLAB APP MAIL INFORMATION', send)
           app.EditField.Value='';
           app.EditField_3.Value='';
           app.EditField_2.Value='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '60f515cb6827ea48b4eb1b3a_Mail_1.gif';
            app.CONTACTUSLabel = uilabel(app.UIFigure);
            app.CONTACTUSLabel.Text = 'CONTACT US';
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = 'Name';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EMailLabel = uilabel(app.UIFigure);
            app.EMailLabel.Text = 'E-Mail';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.ContextLabel = uilabel(app.UIFigure);
            app.ContextLabel.Text = 'Context';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.SENDButton = uibutton(app.UIFigure, 'push');
            app.SENDButton.ButtonPushedFcn = createCallbackFcn(app, @SENDButtonPushed, true);
            app.SENDButton.Text = 'SEND';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SendMail

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end