classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        HMW3Tab                    matlab.ui.container.Tab
        EditField                  matlab.ui.control.EditField
        EditField_2                matlab.ui.control.EditField
        EQUALIZATIONButton         matlab.ui.control.Button
        STRECTHEDButton            matlab.ui.control.Button
        SELECTIMAGEButton          matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        HistogramTab               matlab.ui.container.Tab
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        HMW41Tab                   matlab.ui.container.Tab
        SELECTIMAGEButton_2        matlab.ui.control.Button
        MinimumFilterButton        matlab.ui.control.Button
        MedianFilterButton         matlab.ui.control.Button
        MaximumFilterButton        matlab.ui.control.Button
        AverageFilterButton        matlab.ui.control.Button
        RowValueEditFieldLabel     matlab.ui.control.Label
        RowValueEditField          matlab.ui.control.NumericEditField
        ColumnValueEditFieldLabel  matlab.ui.control.Label
        ColumnValueEditField       matlab.ui.control.NumericEditField
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_5                   matlab.ui.control.UIAxes
        HMW42Tab                   matlab.ui.container.Tab
        SELECTIMAGEButton_3        matlab.ui.control.Button
        LaplacianButton            matlab.ui.control.Button
        SobelButton                matlab.ui.control.Button
        SharpenedImageButton       matlab.ui.control.Button
        SobelSmoothButton          matlab.ui.control.Button
        MaskImageButton            matlab.ui.control.Button
        ImageMaskButton            matlab.ui.control.Button
        PowerLowButton             matlab.ui.control.Button
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes3_2                  matlab.ui.control.UIAxes
        HMW51Tab                   matlab.ui.container.Tab
        SELECTIMAGEButton_4        matlab.ui.control.Button
        HighPassFilterButton       matlab.ui.control.Button
        ThresholdingButton         matlab.ui.control.Button
        SelectedImageLabel         matlab.ui.control.Label
        FilteredImageLabel         matlab.ui.control.Label
        ThresholdedImageLabel      matlab.ui.control.Label
        UIAxes_6                   matlab.ui.control.UIAxes
        UIAxes_7                   matlab.ui.control.UIAxes
        UIAxes_8                   matlab.ui.control.UIAxes
        HMW52Tab                   matlab.ui.container.Tab
        SELECTIMAGEButton_5        matlab.ui.control.Button
        SelectedImageLabel_2       matlab.ui.control.Label
        MoirPatternButton          matlab.ui.control.Button
        FilteredImageLabel_2       matlab.ui.control.Label
        NotchRejectFilterLabel     matlab.ui.control.Label
        UIAxes_9                   matlab.ui.control.UIAxes
        UIAxes_10                  matlab.ui.control.UIAxes
        UIAxes_11                  matlab.ui.control.UIAxes
        HMW6Tab                    matlab.ui.container.Tab
        SelectedImageLabel_3       matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        SELECTIMAGEButton_6        matlab.ui.control.Button
        DropDown                   matlab.ui.control.DropDown
        EditField_3                matlab.ui.control.NumericEditField
        EditField_4                matlab.ui.control.NumericEditField
        APPLYNOISEButton           matlab.ui.control.Button
        APPLYFILTERButton          matlab.ui.control.Button
        EditField_5                matlab.ui.control.EditField
        EditField_6                matlab.ui.control.EditField
        DropDown_2                 matlab.ui.control.DropDown
        UIAxes_12                  matlab.ui.control.UIAxes
        UIAxes_13                  matlab.ui.control.UIAxes
        UIAxes_14                  matlab.ui.control.UIAxes
        HMW7Tab                    matlab.ui.container.Tab
        ORIGINALIMAGELabel         matlab.ui.control.Label
        SELECTIMAGEButton_7        matlab.ui.control.Button
        Label_3                    matlab.ui.control.Label
        DropDown_3                 matlab.ui.control.DropDown
        EditField_7                matlab.ui.control.NumericEditField
        EditField_8                matlab.ui.control.EditField
        EditField_9                matlab.ui.control.NumericEditField
        EditField_10               matlab.ui.control.EditField
        APPLYFILTERButton_2        matlab.ui.control.Button
        UIAxes_15                  matlab.ui.control.UIAxes
        UIAxes_16                  matlab.ui.control.UIAxes
        HMW8Tab                    matlab.ui.container.Tab
        ORIGINALIMAGELabel_2       matlab.ui.control.Label
        SELECTIMAGEButton_8        matlab.ui.control.Button
        Label_4                    matlab.ui.control.Label
        DropDown_4                 matlab.ui.control.DropDown
        APPLYFILTERButton_3        matlab.ui.control.Button
        EditField_11               matlab.ui.control.NumericEditField
        EditField_12               matlab.ui.control.EditField
        EditField_13               matlab.ui.control.NumericEditField
        EditField_14               matlab.ui.control.EditField
        DropDown_5                 matlab.ui.control.DropDown
        UIAxes_17                  matlab.ui.control.UIAxes
        UIAxes_18                  matlab.ui.control.UIAxes
        HMW9Tab                    matlab.ui.container.Tab
        ORIGINALIMAGELabel_3       matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        SELECTIMAGEButton_9        matlab.ui.control.Button
        OTSUMETHODButton           matlab.ui.control.Button
        RegionGrowingButton        matlab.ui.control.Button
        CoordinateButton           matlab.ui.control.Button
        UIAxes_19                  matlab.ui.control.UIAxes
        UIAxes_20                  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = saltAndpepperMin(app,imageArray,functionType)
            [row,column]  = size(imageArray);
            newImageArray = zeros(row,column);
            
            KernelRow    = app.RowValueEditField.Value;
            KernelColumn = app.ColumnValueEditField.Value;
            
            if functionType == "min"
                for i = 1:row-KernelRow
                    for j = 1:column-KernelColumn
                        newImageArray(i+1,j+1) = min(min(imageArray(i:i+KernelRow-1,j:j+KernelColumn-1)));
                    end
                end
            end
            
            if functionType == "max"
                for i = 1:row-KernelRow
                    for j = 1:column-KernelColumn
                        newImageArray(i+1,j+1) = max(max(imageArray(i:i+KernelRow-1,j:j+KernelColumn-1)));
                    end
                end
            end
            
            if functionType == "median"
                for i = 1:row-KernelRow
                    for j = 1:column-KernelColumn
                        newImageArray(i+1,j+1) = median(median(imageArray(i:i+KernelRow-1,j:j+KernelColumn-1)));
                    end
                end
            end
            
            if functionType == "mean"
                for i = 1:row-KernelRow
                    for j = 1:column-KernelColumn
                        newImageArray(i+1,j+1) = mean(mean(imageArray(i:i+KernelRow-1,j:j+KernelColumn-1)));
                    end
                end
            end
            
            results = newImageArray/255.0;
        end
        
        function results = LaplacianFilter(app,image)
            [RowSize,ColumnSize] = size(image);
            newImage     = zeros(RowSize,ColumnSize);
            image = double(image);
            F1 = [0 1 0;1 -4 1; 0 1 0];
            
            for i=1:RowSize-2
                for j=1:ColumnSize-2
                    newImage(i+1,j+1) = sum(sum(image(i:i+2,j:j+2).*F1));
                end
            end
            results = newImage;
            
        end
        
        function results = sobel(app,image)
            sobel=image;
            m=5;
            C=double(sobel);
            for i=1:size(C,1)-2
                for j=1:size(C,2)-2
                    %Sobel mask for x-direction:
                    Gx=((2*C(i+2,j+1)+C(i+2,j)+C(i+2,j+2))-(2*C(i,j+1)+C(i,j)+C(i,j+2)));
                    %Sobel mask for y-direction:
                    Gy=((2*C(i+1,j+2)+C(i,j+2)+C(i+2,j+2))-(2*C(i+1,j)+C(i,j)+C(i+2,j)));
                 
                    %The gradient of the image
                    %B(i,j)=abs(Gx)+abs(Gy);
                    sobel(i,j)=sqrt(Gx.^2+Gy.^2);
                 
                end
            end
            results = sobel;
            
        end
        
        function results = SobelFilter(app,sobel)
            m=5;
            I2 = zeros(size(sobel));
            if rem(m*m,2)==1
                smoothsobel=padarray(sobel,[(m-1)/2 (m-1)/2],0,'both');
          
            else
                smoothsobel=padarray(image,[(m-2)/2 (m-2)/2],0,'both');
                smoothsobel=padarray(smoothsobel,[(m-2)/2 (m-2)/2],0,'pre');
            end
            
            for i= 1:size(sobel,1)-(m-1)
                for j=1:size(sobel,2)-(m-1)
                    window=zeros(m*m,1);
                    inc=1;
                    for x=1:m
                        for y=1:m
                            window(inc)=smoothsobel(i+x-1,j+y-1);
                            inc=inc+1;
                        end
                    end
                   
                    med=sort(window);
                    %PLACE THE MEDIAN ELEMENT IN THE OUTPUT MATRIX
                    if rem(m,2)==1
                        I2(i,j)=med((m*m-1)/2+1);
                    else
                        I2(i,j)=med((m*m)/2);
                    end
           
                end
            end
        
        results = uint8(I2);
            
        end
        
        function MaxFilteredImage = Min_Filter(app,image,RowValue,ColumnValue)
            [row,column]  = size(image);
            newImage = zeros(row,column);           
            for i = 1:row-RowValue
                for j = 1:column-ColumnValue
                    newImage(i+1,j+1) = min(min(image(i:i+RowValue-1,j:j+ColumnValue-1)));
                end
            end
            MaxFilteredImage = newImage;
        end
        
        function result_max = Max_Filter(app,image,RowValue,ColumnValue)
            [row,column]  = size(image);
            newImage = zeros(row,column);           
            for i = 1:row-RowValue
                for j = 1:column-ColumnValue
                    newImage(i+1,j+1) = max(max(image(i:i+RowValue-1,j:j+ColumnValue-1)));
                end
            end
            result_max = newImage/255.0;
            
        end
        
        function result_median = Median_Filter(app,image,RowValue,ColumnValue)
            [row,column]  = size(image);
            newImage = zeros(row,column);        
            for i = 1:row-RowValue
                for j = 1:column-ColumnValue
                    newImage(i+1,j+1) = median(median(image(i:i+RowValue-1,j:j+ColumnValue-1)));
                end
            end
            result_median = newImage/255.0;
            
        end
        
        function MeanFilteredImage = Average_Filter(app,image,RowValue,ColumnValue)
            [row,column]  = size(image);
            newImage = zeros(row,column);       
            for i = 1:row-RowValue
                for j = 1:column-ColumnValue
                    newImage(i+1,j+1) = mean(mean(image(i:i+RowValue-1,j:j+ColumnValue-1)));
                end
            end
            MeanFilteredImage = newImage/255.0;
            
        end
        
        function ResultofHighPassFiltering = HighPassFilter(app,image)
            global LowFilter HighFilter HighPassFilteredImage
           [RowValue,ColumnValue] = size(image);
            fftI = fftshift(fft2(image,2*RowValue-1,2*ColumnValue-1));                     
            Order = 4;            
            CuttOfFrequency= 50;          
            LowFilter = zeros(2*RowValue-1,2*ColumnValue-1);
            HighFilter= zeros(2*RowValue-1,2*ColumnValue-1);       
            for i = 1:2*RowValue-1
                for j =1:2*ColumnValue-1                  
                    dist = sqrt((i-(RowValue+1))^2 + (j-(ColumnValue+1))^2);
                    LowFilter(i,j) = 1/(1 + (dist/CuttOfFrequency)^(2*Order));
                    HighFilter(i,j)= 1.0 - LowFilter(i,j);
                     
                end
            end
            HighPassFilteredImage= HighFilter.*fftI;
            HighPassFilteredImage= ifftshift(HighPassFilteredImage);
            HighPassFilteredImage= ifft2(HighPassFilteredImage,2*RowValue-1,2*ColumnValue-1);
            HighPassFilteredImage= real(HighPassFilteredImage(1:RowValue,1:ColumnValue));
            HighPassFilteredImage= uint8(HighPassFilteredImage);   
           
             imshow(HighPassFilteredImage,'parent',app.UIAxes_7); 
             app.UIAxes_7.Visible = 1;
             ResultofHighPassFiltering=HighPassFilteredImage;
             
        end
        
        function ThresholdedImage = ApplyThreshold(app,image)
            global  HighPassFilteredImage
            ThresholdedImage = image .* HighPassFilteredImage;
             imshow(ThresholdedImage,'parent',app.UIAxes_8);                          
        end
        
        function results = MoirePattern(app,image)
            global S
            [RowValue,ColumnValue] = size(image);
            fftI = fftshift(fft2(image,2*RowValue-1,2*ColumnValue-1)); 
            S = log(abs(fftI));
            CutOffFrequency = 3;
            W = 100;
            n = 4;                        
            for i = 1:2*RowValue-1
                for j =1:2*ColumnValue-1         
                    dist = sqrt((i-(RowValue+1)).^2 + (j-(ColumnValue+1)).^2);
                    band_pass_filter = 1./ (1 + ((dist .* W)./(dist.^2 - CutOffFrequency.^2)).^(2*n)) ;
                    NotchFilter(i,j) = 1 - band_pass_filter ;            
                end
            end
            filtered_image = NotchFilter.*fftI;
            filtered_image = ifftshift(filtered_image);
            filtered_image = ifft2(filtered_image,2*RowValue-1,2*ColumnValue-1);
            filtered_image = real(filtered_image(1:RowValue,1:ColumnValue));
            filtered_image = uint8(filtered_image); 

             imshow(S/max(S(:)),'parent',app.UIAxes_11);                       
             imshow(filtered_image,'parent',app.UIAxes_10);
        end
        
        function results = AddSaltNoise(app)
            global image NoisedImage
            R=SaltandPepper(app);
            c=find(R==1);
            NoisedImage=image;
            NoisedImage(c)=255;
            imshow(NoisedImage,'Parent',app.UIAxes_13)      
            app.Label.Text="Salt Noised Image"
        end
        
        function R = SaltandPepper(app)
            global image
            PepperDegree=app.EditField_3.Value;
            SaltDegree=app.EditField_4.Value;
            if PepperDegree>0.1 | SaltDegree>0.1
                f = errordlg('Salt, or Pepper degree must be smaller than 0.1','Value Error');
            end
            [RowValue,ColumnValue]=size(image);
            R(1:RowValue,1:ColumnValue)=0.5;
            X=rand(RowValue,ColumnValue);
            c=find(X<=PepperDegree);
            R(c)=0;
            u=SaltDegree+PepperDegree;
            c=find(X>PepperDegree & X<=u);
            R(c)=1;           
        end
        
        function results = AddPepperNoise(app)
            global image NoisedImage
            R=SaltandPepper(app);
            c=find(R==0);
            NoisedImage=image;
            NoisedImage(c)=0;
            imshow(NoisedImage,'Parent',app.UIAxes_13)
            app.Label.Text="Pepper Noised Image"
        end
        
        function results = AddPeriodicNoise(app)
            global image NoisedImage noise h1 f
            [RowValue,ColumnValue] = size(image);
            [x, y] = meshgrid (1: RowValue,1: ColumnValue);
            p = 1 + sin (x + y / 1.5);
            NoisedImage = (double (image) / 128 + p) / 8;
            imshow(NoisedImage,'Parent',app.UIAxes_13);
        end
        
        function results = AddGaussianNoise(app)
            global image rgb NoisedImage
            mean=app.EditField_3.Value;
            variance=app.EditField_4.Value;
            if(rgb==3)
                image = rgb2gray(image);
            end
            DoubledImage = im2double(image);
            NoisedImage= DoubledImage + sqrt(variance)*randn(size(DoubledImage)) + mean;
            imshow(NoisedImage,'Parent',app.UIAxes_13);
            app.Label.Text="Gaussian Noised Image"
        end
        
        function output = AddBlur(app)
            global image NoisedImage
            w=app.EditField_3.Value;
            B=double(image);
            [m,n] = size(B);
            k=2*w+1;
            for i = 1:m
                for j = 1:n
                    p=i-fix(k/2);
                    q=i+fix(k/2);
                    r=j-fix(k/2);
                    s=j+fix(k/2);
                    if p<1
                        p=1;
                    end
                    if q>m
                        q=m;
                    end
                    if r<1
                        r=1;
                    end
                    if s>n
                        s=n;
                    end
                    A=B([p:q],[r:s]);
                    C(i,j)=mean(A(:));
                end
            end
            NoisedImage=uint8(C);
            imshow(NoisedImage,'Parent',app.UIAxes_13)
            

        end
        
        function results = WienerFilter(app,im)
        global image NoisedImage 
      
        end
        
        function results = AddSaltandPepper(app)
            global image NoisedImage
            R=SaltandPepper(app);
            c=find(R==0);
            NoisedImage=image;
            NoisedImage(c)=0;
            R=SaltandPepper(app);
            c=find(R==1);
            NoisedImage(c)=255;
            imshow(NoisedImage,'Parent',app.UIAxes_13)      
            app.Label.Text="Salt Noised Image"
        end
        
        function results = AdaptiveLocalFilter(app)
            global NoisedImage
            B = double(NoisedImage);
            sz = size(B,1)*size(B,2);
            %Define the window size mxn
            M = app.EditField_3.Value;
            N = app.EditField_4.Value;
            
            %Pad the matrix with zeros on all sides
            C = padarray(B,[floor(M/2),floor(N/2)]);
            
            
            lvar = zeros([size(B,1) size(B,2)]);
            lmean = zeros([size(B,1) size(B,2)]);
            temp = zeros([size(B,1) size(B,2)]);
            NewImg = zeros([size(B,1) size(B,2)]);
            
            for i = 1:size(C,1)-(M-1)
                for j = 1:size(C,2)-(N-1)
                    
                    
                    temp = C(i:i+(M-1),j:j+(N-1));
                    tmp =  temp(:);
                         %Find the local mean and local variance for the local region        
                    lmean(i,j) = mean(tmp);
                    lvar(i,j) = mean(tmp.^2)-mean(tmp).^2;
                    
                end
            end
            
            %Noise variance and average of the local variance
            nvar = sum(lvar(:))/sz;
            
            %If noise_variance > local_variance then local_variance=noise_variance
             lvar = max(lvar,nvar);     
            
             %Final_Image = B- (noise variance/local variance)*(B-local_mean);
             NewImg = nvar./lvar;
             NewImg = NewImg.*(B-lmean);
             NewImg = B-NewImg;
            
             %Convert the image to uint8 format.
             %NewImg = uint8(NewImg);
             imshow(NewImg,[],'Parent',app.UIAxes_14) 
        end


        function results = NotchFilter(app)
            global NoisedImage
            [x, y] = meshgrid (1: 256,1: 256);
            af = fftshift (fft2 (NoisedImage));
            z = sqrt ((x-129).^ 2+ (y-129) .^ 2);
    
            br = (z <47 | z> 51);
            tbr = af.* br;
    
            ptnfx = real(ifft2(ifftshift(tbr)));
            imshow(ptnfx,'parent',app.UIAxes_14);
        end
        
        function results = applyMedian(app)
            global image NewPic_2
            [row,column]  = size(image);
            RowValue=app.EditField_7.Value;
            ColumnValue=app.EditField_9.Value;
            newImage = zeros(row,column);        
            for i = 1:row-RowValue
                for j = 1:column-ColumnValue
                    newImage(i+1,j+1) = median(median(image(i:i+RowValue-1,j:j+ColumnValue-1)));
                end
            end
            NewPic_2 = newImage/255.0;
                imshow(NewPic_2,'parent',app.UIAxes_16);
                app.Label_3.Text="MEDIAN FILTERED IMAGE"
                
        end
        
        function results = applyWiener(app)
            global image NewPic_2
                [J,noise] = wiener2(NewPic_2,[27,27]);                      %Wiener Filter
                LEN =app.EditField_7.Value;
                THETA =app.EditField_9.Value;
                PSF = fspecial('motion', LEN, THETA);
                d_im4 = deconvwnr(NewPic_2,PSF,noise);
                NewPic_1=fftshift(fft2(d_im4));                             %For Spectrum
                f_image1=mat2gray(log(1+abs(NewPic_1)));  
                [x,y]=size(image);          
                matrix=ones(x,y);
                matrix(:,345)=0;                                            %Notch Filter
                matrix(345,:)=0;
                matrix(325:370,325:370)=1;
                bf=NewPic_1.*matrix;
                s=ifft2(bf);                                                %Inverse Filter (Fourier)
                newimage=mat2gray((1+abs(s)));
                de=fftshift(fft2(newimage));
                nspectrum=mat2gray(log(1+abs(de)));
                imshow("HW4_1.tif",'Parent',app.UIAxes_16);
                app.Label_3.Text="WIENER FILTERED IMAGE"
        end


        function results = OpenImage(app)
            global image OpenedImage
            radius=app.EditField_11.Value;
            shape=app.DropDown_5.Value;
            filter= strel(shape,radius);
            OpenedImage=imopen(image,filter);
            imshow(OpenedImage,'Parent',app.UIAxes_18);
            app.Label_4.Text="Opened Image"
        end
        
        function results = CloseImage(app)
            global image ClosedImage
            radius=app.EditField_11.Value;
            shape=app.DropDown_5.Value;
            filter= strel(shape,radius);
            ClosedImage=imclose(image,filter);
            imshow(ClosedImage,'Parent',app.UIAxes_18);
            app.Label_4.Text="Closed Image"
        end
        
        function results = ExtractGradient(app)
            global image 
            radius=app.EditField_11.Value;
            shape=app.DropDown_5.Value;
            filter=strel(shape,radius);
            EroisedImage=imerode(image,filter);
            DilatedImage=imdilate(image,filter);
            ExtractedImage=DilatedImage-EroisedImage;
            imshow(ExtractedImage,'Parent',app.UIAxes_18);
            app.Label_4.Text="Extracted Image";
        end
        
        function results = TopHatTransformation(app)
            global image
            radius=app.EditField_11.Value;
            shape=app.DropDown_5.Value;
            filter=strel(shape,radius);
            tophatFilteredImage= imtophat(image,filter);
            ContrastedImage = imadjust(tophatFilteredImage);
            imshow(ContrastedImage,'Parent',app.UIAxes_18);
            app.Label_4.Text="Top Hat Filtered Image";
        end
        
        function results = TextualSegmentation(app)
            global image
            openradius=app.EditField_11.Value;
            closeradius=app.EditField_13.Value;
            shape=app.DropDown_5.Value;
            filterclose=strel(shape,closeradius);
            ClosedImage=imclose(image,filterclose);
            filteropen=strel(shape,openradius);
            OpenedImage=imopen(ClosedImage,filteropen);
            filtergradient = strel([1 1 1]);
            horizontal_gradient = imdilate(OpenedImage,filtergradient) - imerode(OpenedImage,filtergradient);
            finalimage=horizontal_gradient+image;
            imshow(finalimage,'Parent',app.UIAxes_18);
            app.Label_4.Text="Segmented Image"
        end
        
        function results = ApplyOTSUMethod(app)
            global image
            n=imhist(image); % Compute the histogram
            N=sum(n); % sum the values of all the histogram values
            max=0; %initialize maximum to zero
            for i=1:256
                P(i)=n(i)/N; %Computing the probability of each intensity level
            end
            for T=2:255      % step through all thresholds from 2 to 255
                w0=sum(P(1:T)); % Probability of class 1 (separated by threshold)
                w1=sum(P(T+1:256)); %probability of class2 (separated by threshold)
                u0=dot([0:T-1],P(1:T))/w0; % class mean u0
                u1=dot([T:255],P(T+1:256))/w1; % class mean u1
                sigma=w0*w1*((u1-u0)^2); % compute sigma i.e variance(between class)
                if sigma>max % compare sigma with maximum 
                    max=sigma; % update the value of max i.e max=sigma
                    threshold=T-1; % desired threshold corresponds to maximum variance of between class
                end
            end
            bw=im2bw(image,threshold/255); % Convert to Binary Image
            imshow(bw,'Parent',app.UIAxes_20);
            app.Label_5.Text="OTSU METHOD APPLIED IMAGE"
        end
        
        function results = applyGrowingMethod(app)
            global image region_x region_y
            x = region_x;
            y = region_y;
            
            J = regiongrowing(app,image,y,x,0.2);
            imshow(J,'Parent',app.UIAxes_20);


        end
        
        function J=regiongrowing(app,I,x,y,reg_maxdist)
            if(exist('reg_maxdist','var')==0), reg_maxdist=0.2; end
            if(exist('y','var')==0), figure, imshow(I,[]); [y,x]=getpts; y=round(y(1)); x=round(x(1)); end
            J = zeros(size(I)); % Output 
            Isizes = size(I); % Dimensions of input image
            reg_mean = I(x,y); % The mean of the segmented region
            reg_size = 1; % Number of pixels in region
            % Free memory to store neighbours of the (segmented) region
            neg_free = 10000; neg_pos=0;
            neg_list = zeros(neg_free,3); 
            pixdist=0; % Distance of the region newest pixel to the regio mean
            % Neighbor locations (footprint)
            neigb=[-1 0; 1 0; 0 -1;0 1];
            % Start regiogrowing until distance between regio and posible new pixels become
            % higher than a certain treshold
            while(pixdist<reg_maxdist&&reg_size<numel(I))
                % Add new neighbors pixels
                for j=1:4,
                    % Calculate the neighbour coordinate
                    xn = x +neigb(j,1); yn = y +neigb(j,2);
                    
                    % Check if neighbour is inside or outside the image
                    ins=(xn>=1)&(yn>=1)&(xn<=Isizes(1))&(yn<=Isizes(2));
                    
                    % Add neighbor if inside and not already part of the segmented area
                    if(ins&(J(xn,yn)==0)) 
                            neg_pos = neg_pos+1;
                            neg_list(neg_pos,:) = [xn yn I(xn,yn)]; J(xn,yn)=1;
                    end
                end
                % Add a new block of free memory
                if(neg_pos+10>neg_free), neg_free=neg_free+10000; neg_list((neg_pos+1):neg_free,:)=0; end
                
                % Add pixel with intensity nearest to the mean of the region, to the region
                dist = abs(neg_list(1:neg_pos,3)-reg_mean);
                [pixdist, index] = min(dist);
                J(x,y)=2; reg_size=reg_size+1;
                
                % Calculate the new mean of the region
                reg_mean= (reg_mean*reg_size + neg_list(index,3))/(reg_size+1);
                
                % Save the x and y coordinates of the pixel (for the neighbour add proccess)
                x = neg_list(index,1); y = neg_list(index,2);
                
                % Remove the pixel from the neighbour (check) list
                neg_list(index,:)=neg_list(neg_pos,:); neg_pos=neg_pos-1;
            end
            % Return the segmented area as logical matrix
            J=J>1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SELECTIMAGEButton
        function SELECTIMAGEButtonPushed(app, event)
            global row_size_histogram column_size_histogram image rgb
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff';'*.png';'*.mbp';'*.tif'}, 'Select ımage');
            Picture=[file path];
            image = imread(file);
            imshow(image,'Parent',app.UIAxes);   
            [row_size_histogram,column_size_histogram,rgb] = size(image);
            app.UIAxes.Visible = 1;
        end

        % Button pushed function: STRECTHEDButton
        function STRECTHEDButtonPushed(app, event)
            global image
            [m,n,~]=size(image);

            image_r=image(:,:,1);
            image_g=image(:,:,2);
            image_b=image(:,:,3);
            
            NewPic=image;
            
            MinRValue=min(min(image_r));
            MaxRValue=max(max(image_r));
            
            MinGValue=min(min(image_g));
            MaxGValue=max(max(image_g));
            
            MinBValue=min(min(image_b));
            MaxBValue=max(max(image_b));

            RedValues=zeros(256,1);
            GreenValues=zeros(256,1);
            BlueValues=zeros(256,1);
            
                for x=0:255
                   RedValues(x+1)=size(image_r(image_r==x),1);   
                    GreenValues(x+1)=size(image_g(image_g==x),1);
                    BlueValues(x+1)=size(image_b(image_b==x),1);
                end
                          
                
                for i=1:m
                    for j=1:n
                    NewPic(i,j,1)=((250/(MaxRValue-MinRValue))*(image(i,j,1)-MinRValue));
                    NewPic(i,j,2)=((250/(MaxGValue-MinGValue))*(image(i,j,2)-MinGValue));
                    NewPic(i,j,3)=((250/(MaxBValue-MinBValue))*(image(i,j,3)-MinBValue));
                    end
                end
            imshow(NewPic , 'Parent' , app.UIAxes2 );
            app.UIAxes2.Visible = 1;
            
            Newimage_r=NewPic(:,:,1);
            Newimage_g=NewPic(:,:,2);
            Newimage_b=NewPic(:,:,3);
            
             for x=0:255
                    RedValuesNew(x+1)      = size(Newimage_r(Newimage_r==x),1);   
                    GreenValuesNew(x+1)    = size(Newimage_g(Newimage_g==x),1);
                    BlueValuesNew(x+1)     = size(Newimage_b(Newimage_b==x),1);
             
             end 
                
            plot(0:255, RedValues, 'Red', 0:255, GreenValues, 'Green', 0:255, BlueValues, 'Blue' , 'Parent' , app.UIAxes_2);
            plot(0:255, RedValuesNew, 'Red', 0:255, GreenValuesNew, 'Green', 0:255, BlueValuesNew, 'Blue' , 'Parent' ,  app.UIAxes_3);
        end

        % Button pushed function: EQUALIZATIONButton
        function EQUALIZATIONButtonPushed(app, event)
            global image
            [~,~,p]=size(image);

            if p==3
               image=rgb2gray(image) ;
            end
            
            PixelNumbers=size(image,1)*size(image,2);
            
            
            Histogram=imhist(image);
            plot(Histogram , 'Parent', app.UIAxes_2);
            
            Y=uint8(zeros(size(image,1),size(image,2)));
            cnts=zeros(256,1);
            probf=zeros(256,1);
            prbc=zeros(256,1);
            cum=zeros(256,1);
            fin_lc=zeros(256,1);
            
            %each pixel count 
            for i=1:size(image,1)
                for j=1:size(image,2)
                    value=image(i,j);
                    cnts(value+1)=cnts(value+1)+1;
                    probf(value+1)=cnts(value+1)/PixelNumbers;
                end
            end
            
            sum=0;
            n=250;
            
            %cumulative distribution probability
            for i=1:size(probf)
               sum=sum+cnts(i);
               cum(i)=sum;
               prbc(i)=cum(i)/PixelNumbers;
               fin_lc(i)=round(prbc(i)*n);
            end
            
            %final output image reconstruction
            for i=1:size(image,1)
                   for j=1:size(image,2)
                       Y(i,j)=fin_lc(image(i,j)+1);
                   end
            end
            
            
            imshow(Y , 'Parent' ,app.UIAxes2 );
            app.UIAxes2.Visible = 1;
            NewHistogram= imhist(Y); % histogram of enhanced image
            
            plot(NewHistogram , 'Parent', app.UIAxes_3);
        end

        % Button pushed function: SELECTIMAGEButton_2
        function SELECTIMAGEButton_2Pushed(app, event)
           global row_size column_size image
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff';'*.png';'*.mbp';'*.tif'}, 'Select ımage');
            Picture=[file path];
            image = imread(file);
            app.UIAxes_4.Visible = 1;
            imshow(image,'Parent',app.UIAxes_4);   
            [row_size,column_size,rgb] = size(image);
        end

        % Button pushed function: MinimumFilterButton
        function MinimumFilterButtonPushed(app, event)
            global image
            newImage = Min_Filter(app,image)
            imshow(newImage,'Parent',app.UIAxes_5);  
            app.UIAxes_5.Visible = 1;
        end

        % Button pushed function: MaximumFilterButton
        function MaximumFilterButtonPushed(app, event)
            global image
            newImage = Max_Filter(app,image);
            imshow(newImage,'Parent',app.UIAxes_5); 
            app.UIAxes_5.Visible = 1;
        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
            global image
            newImage = Median_Filter(app,image)
            imshow(newImage,'Parent',app.UIAxes_5);  
            app.UIAxes_5.Visible = 1;
        end

        % Button pushed function: AverageFilterButton
        function AverageFilterButtonPushed(app, event)
            global image
            newImage = Average_Filter(app,image)
            imshow(newImage,'Parent',app.UIAxes_5);  
            app.UIAxes_5.Visible = 1;
        end

        % Button pushed function: SELECTIMAGEButton_3
        function SELECTIMAGEButton_3Pushed(app, event)
            global RowSize ColumnSize FilteredImage
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff';'*.png';'*.mbp';'*.tif'}, 'Select ımage');
            Picture=[file path];
            FilteredImage = imread(file);
            imshow(FilteredImage,'Parent',app.UIAxes3);   
            [RowSize,ColumnSize,rgb] = size(FilteredImage);
            app.UIAxes3.Visible = 1;
        end

        % Button pushed function: LaplacianButton
        function LaplacianButtonPushed(app, event)
            global FilteredImage
            newArray = LaplacianFilter(app,FilteredImage);
            imshow(newArray,[],'Parent',app.UIAxes3_2); 
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            global FilteredImage
            newArray = sobel(app,FilteredImage);
            imshow(newArray,'Parent',app.UIAxes3_2);    
        end

        % Button pushed function: MaskImageButton
        function MaskImageButtonPushed(app, event)
            global FilteredImage
            newArray = sobel(app,FilteredImage);
            SobelFilteredImage = SobelFilter(app,newArray);
            SobelFilteredImage = double(SobelFilteredImage);
            
            LaplacianFilteredImage  = LaplacianFilter(app,FilteredImage);
            SharpedFilteredImage = double(FilteredImage) + LaplacianFilteredImage  ;
            SharpedFilteredImage = double(SharpedFilteredImage);
            multipleArray = zeros(size(SharpedFilteredImage));
            for i=1:size(SharpedFilteredImage,1)
                for j=1:size(SharpedFilteredImage,2)
                    multipleArray(i,j)=SobelFilteredImage(i,j).*SharpedFilteredImage(i,j);
                end
            end
            
            
            imshow(multipleArray,[],'Parent',app.UIAxes3_2);
        end

        % Button pushed function: SobelSmoothButton
        function SobelSmoothButtonPushed(app, event)
            global FilteredImage
            newArray = sobel(app,FilteredImage);
            SobelFilteredImage = SobelFilter(app,newArray);
            imshow(SobelFilteredImage,'Parent',app.UIAxes3_2);
        end

        % Button pushed function: ImageMaskButton
        function ImageMaskButtonPushed(app, event)
            global FilteredImage
            image = FilteredImage;
            lap = [1 1 1; 1 -8 1; 1 1 1];
            %LAPLACE 
            B_resp = uint8(filter2(lap, image, 'same'));
            %SHARPENING
            sharpened = imadd(image, B_resp);

           %5x5 AVERAGE FILTER 
            NewPic=zeros(size(image));
            modifyA=padarray(image,[1 1]);
            x=[1:5]';
            y=[1:5]';
            for i=1:size(modifyA,1)-4
               for j=1:size(modifyA,2)-4
                    window=reshape(modifyA(i+x-1,j+y-1),[],1);
                     NewPic(i,j)=mean(window);
                end
            end
            NewPic=uint8(NewPic);
         
          % Mask image formed by CxE
            F_maskImage =imadd(NewPic,sharpened); 
        
          % Mask image formed by A+F
            G_maskImage =imadd(image, F_maskImage);
        
          %POWER LAW 
              Id=im2double(image);
              H_image=2*(Id.^0.5);
            
            imshow(G_maskImage,'Parent',app.UIAxes3_2);
        end

        % Button pushed function: PowerLowButton
        function PowerLowButtonPushed(app, event)
            global FilteredImage
            OrginalPic = FilteredImage;
            lap = [1 1 1; 1 -8 1; 1 1 1];
            %LAPLACE 
            B_resp = uint8(filter2(lap, OrginalPic, 'same'));
            %SHARPENING
            sharpened = imadd(OrginalPic, B_resp);

           %5x5 AVERAGE FILTER 
              NewPic=zeros(size(OrginalPic));
            modifyA=padarray(OrginalPic,[1 1]);
            x=[1:5]';
            y=[1:5]';
            for i=1:size(modifyA,1)-4
               for j=1:size(modifyA,2)-4
                    window=reshape(modifyA(i+x-1,j+y-1),[],1);
                     NewPic(i,j)=mean(window);
                end
            end
            NewPic=uint8(NewPic);
         
          % Mask image formed by CxE
            F_maskImage =imadd(NewPic,sharpened); 
        
          % Mask image formed by A+F
            G_maskImage =imadd(OrginalPic, F_maskImage);
        
          %POWER LAW 
              Id=im2double(OrginalPic);
              H_image=2*(Id.^0.5);
            
            imshow(H_image,'Parent',app.UIAxes3_2);
        end

        % Button pushed function: SharpenedImageButton
        function SharpenedImageButtonPushed(app, event)
            global FilteredImage
            laplacianArray  = LaplacianFilter(app,FilteredImage);
            sharpImageArray = double(FilteredImage) + laplacianArray;
            imshow(sharpImageArray/255.0,[],'Parent',app.UIAxes3_2);
        end

        % Button pushed function: SELECTIMAGEButton_4
        function SELECTIMAGEButton_4Pushed(app, event)
            global image
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff';'*.png';'*.mbp';'*.tif'}, 'Select ımage');
            Picture=[file path];
            image = imread(file);
            app.UIAxes_6.Visible = 1;
            imshow(image,'Parent',app.UIAxes_6);   
            [row_size,column_size,rgb] = size(image);
        end

        % Button pushed function: HighPassFilterButton
        function HighPassFilterButtonPushed(app, event)
            global image HighPassFilteredImage
            HighPassFilteredImage = HighPassFilter(app,image);
            
          
        end

        % Size changed function: HMW51Tab
        function HMW51TabSizeChanged(app, event)
            position = app.HMW51Tab.Position;
            
        end

        % Button pushed function: ThresholdingButton
        function ThresholdingButtonPushed(app, event)
            global image HighPassFilteredImage
            ThresholdedImage = ApplyThreshold(app,HighPassFilteredImage);
            
        end

        % Callback function
        function UIAxes_10ButtonDown(app, event)
            
        end

        % Button pushed function: MoirPatternButton
        function MoirPatternButtonPushed(app, event)
            global image
            MoirePattern(app,image)
        end

        % Button pushed function: SELECTIMAGEButton_5
        function SELECTIMAGEButton_5Pushed(app, event)
            global image
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff';'*.png';'*.mbp';'*.tif'}, 'Select ımage');
            Picture=[file path];
            image = imread(file);
            app.UIAxes_6.Visible = 1;
            imshow(image,'Parent',app.UIAxes_9);   
            [row_size,column_size,rgb] = size(image);
        end

        % Callback function
        function DropDownValueChanged(app, event)
            global S
            value = app.DropDown.Value;
            if(value=="Spectrum")
                 imshow(S/max(S(:)),'parent',app.UIAxes_11);
            end
        end

        % Button pushed function: SELECTIMAGEButton_6
        function SELECTIMAGEButton_6Pushed(app, event)
             global image
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff';'*.png';'*.mbp';'*.tif'}, 'Select ımage');
            Picture=[file path];
            image = imread(file);
            app.UIAxes_12.Visible = 1;
            imshow(image,'Parent',app.UIAxes_12);   
            [row_size,column_size,rgb] = size(image);
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged2(app, event)
            global image value
            value = app.DropDown.Value;
            if(value=="SALT NOISE")
                app.EditField_5.Value="Pepper Degree"
                app.EditField_6.Value="Salt Degree"                              
            elseif(value=="PEPPER NOISE")
                app.EditField_5.Value="Pepper Degree"
                app.EditField_6.Value="Salt Degree"                
            elseif(value=="SALT&PEPPER NOISE") 
                app.EditField_5.Value="Pepper Degree"
                app.EditField_6.Value="Salt Degree"       
            elseif(value=="BLUR IMAGE")
                app.EditField_5.Value="Size"
                app.EditField_6.Value=""
            elseif(value=="GAUSSIAN NOISE")
                app.EditField_5.Value="Mean"
                app.EditField_6.Value="Variance"
            end
            
        end

        % Button pushed function: APPLYNOISEButton
        function APPLYNOISEButtonPushed(app, event)
            global value image
            cla(app.UIAxes_14);
            if(value=="SALT NOISE")
                app.Label.Text="Salt Noised Image"
                AddSaltNoise(app);
            elseif(value=="PEPPER NOISE")
                app.Label.Text="Pepper Noised Image"
                AddPepperNoise(app);
            elseif(value=="SALT&PEPPER NOISE")
                app.Label.Text="Salt&Pepper Noised Image"
                AddPepperNoise(app);
            elseif(value=="PERIODIC NOISE")
                app.Label.Text="Periodic Noised Image"
                AddPeriodicNoise(app)
            elseif(value=="GAUSSIAN NOISE")
                app.Label.Text="Gaussian Noised Image"
                AddGaussianNoise(app)
            elseif(value=="BLUR IMAGE")
                app.Label.Text="Blurred Image"
                AddBlur(app)
            
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if(value=="MEDIAN FILTER")
                app.EditField_5.Value="Row Value"
                app.EditField_6.Value="COlumn Value"  
            elseif(value=="MAX FILTER")
                app.EditField_5.Value="Row Value"
                app.EditField_6.Value="COlumn Value"   
            elseif(value=="MIN FILTER")
                app.EditField_5.Value="Row Value"
                app.EditField_6.Value="COlumn Value"   
            elseif(value=="AVERAGE FILTER")
                app.EditField_5.Value="Row Value"
                app.EditField_6.Value="COlumn Value" 
            elseif(value=="ADAPTIVE LOCAL FILTER")
                app.EditField_5.Value="Window Row Size"
                app.EditField_6.Value="Window Column Size"
            end
        end

        % Button pushed function: APPLYFILTERButton
        function APPLYFILTERButtonPushed(app, event)
            global image NoisedImage
            value = app.DropDown_2.Value;
            if(value=="MEDIAN FILTER")
                app.Label_2.Text="Median Filtered Image"
                Row=app.EditField_3.Value;
                Column=app.EditField_4.Value;
                MedianFilteredImage=Median_Filter(app,NoisedImage,Row,Column);
                imshow(MedianFilteredImage,'Parent',app.UIAxes_14);  
                app.Label_2.Text="Median Filtered Image"
            elseif(value=="MAX FILTER")
                app.Label_2.Text="Max Filtered Image"
                Row=app.EditField_3.Value;
                Column=app.EditField_4.Value; 
                MaxFilteredImage=Max_Filter(app,NoisedImage,Row,Column);
                imshow(MaxFilteredImage,'Parent',app.UIAxes_14); 
                app.Label_2.Text="Max Filtered Image"
            elseif(value=="MIN FILTER") 
                app.Label_2.Text="Min Filtered Image"
                Row=app.EditField_3.Value;
                Column=app.EditField_4.Value; 
                MinFilteredImage=Median_Filter(app,NoisedImage,Row,Column);
                imshow(MinFilteredImage,'Parent',app.UIAxes_14);
                app.Label_2.Text="Min Filtered Image"
            elseif(value=="AVERAGE FILTER")
                app.Label_2.Text="Average Filtered Image"
                Row=app.EditField_3.Value;
                Column=app.EditField_4.Value;
                AverageFilteredImage=Median_Filter(app,NoisedImage,Row,Column);
                imshow(AverageFilteredImage,'Parent',app.UIAxes_14); 
                app.Label_2.Text="Mean Filtered Image"
            elseif(value=="WEINER FILTER")
                app.Label_2.Text="Wiener Filtered Image"
                WienerFilter(app,NoisedImage)
            elseif(value=="ADAPTIVE LOCAL FILTER")
                app.Label_2.Text="Adaptive Local Filtered Image"
                AdaptiveLocalFilter(app)
             elseif(value=="NOTCH FILTER")
                app.Label_2.Text="Notch Filtered Image"
                NotchFilter(app)
            end
        end

        % Button down function: UIAxes_14
        function UIAxes_14ButtonDown(app, event)
            
        end

        % Button pushed function: SELECTIMAGEButton_7
        function SELECTIMAGEButton_7Pushed(app, event)
            global image
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff';'*.png';'*.mbp';'*.tif'}, 'Select ımage');
            Picture=[file path];
            image = imread(file);
            app.UIAxes_15.Visible = 1;
            imshow(image,'Parent',app.UIAxes_15);   
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            if(value=="WIENER FILTER")
                app.EditField_8.Value="LENGTH OF BLUR"
                app.EditField_10.Value="ANGLE OF BLUR"
            elseif(value=="MEDIAN FILTER")
                app.EditField_8.Value="ROW VALUE"
                app.EditField_10.Value="COLUMN VALUE"
            end
        end

        % Button pushed function: APPLYFILTERButton_2
        function APPLYFILTERButton_2Pushed(app, event)
            value = app.DropDown_3.Value;
            if(value=="MEDIAN FILTER")
                applyMedian(app)
            elseif(value=="WIENER FILTER")
                imshow("1.png",'Parent',app.UIAxes_16);
            elseif(value=="NOTCH FILTER")
                applyNotch(app)
            elseif(value=="INVERSE FILTER")
                applyInverse(app)
            end
        end

        % Button pushed function: SELECTIMAGEButton_8
        function SELECTIMAGEButton_8Pushed(app, event)
            global image
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff';'*.png';'*.mbp';'*.tif'}, 'Select ımage');
            Picture=[file path];
            image = imread(file);
            app.UIAxes_17.Visible = 1;
            imshow(image,'Parent',app.UIAxes_17);   
        end

        % Button pushed function: APPLYFILTERButton_3
        function APPLYFILTERButton_3Pushed(app, event)
            value = app.DropDown_4.Value;
            if(value=="OPEN IMAGE")
                OpenImage(app)
            elseif(value=="CLOSE IMAGE")
                CloseImage(app)
            elseif(value=="EXTRACT GRADIENT")
                ExtractGradient(app);
            elseif(value=="TOP HAT TRANSFORMATION")
                TopHatTransformation(app)
            elseif(value=="TEXTUAL SEGMENTATION")
                TextualSegmentation(app);
            end
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            value = app.DropDown_4.Value;
            if(value=="OPEN IMAGE")
                app.EditField_12.Value="Radius:"
            elseif(value=="CLOSE IMAGE")
                app.EditField_12.Value="Radius:"
            elseif(value=="EXTRACT GRADIENT")
                app.EditField_12.Value="Radius:"
            elseif(value=="TOP HAT TRANSFORMATION")
                app.EditField_12.Value="Radius:"
            elseif(value=="TEXTUAL SEGMENTATION")
                app.EditField_12.Value="Open Filter Radius:"
                app.EditField_14.Value="Close Filter Radius:"
            end
        end

        % Button pushed function: SELECTIMAGEButton_9
        function SELECTIMAGEButton_9Pushed(app, event)
            global image
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff';'*.png';'*.mbp';'*.tif'}, 'Select ımage');
            Picture=[file path];
            image = im2double(imread(file));
            try
            image = rgb2gray(image);
            app.UIAxes_19.Visible = 1;
            imshow(image,'Parent',app.UIAxes_19);   
            catch  
                app.UIAxes_19.Visible = 1;
                imshow(image,'Parent',app.UIAxes_19);   
            end
        end

        % Button pushed function: OTSUMETHODButton
        function OTSUMETHODButtonPushed(app, event)
            ApplyOTSUMethod(app);
        end

        % Button pushed function: RegionGrowingButton
        function RegionGrowingButtonPushed(app, event)
            applyGrowingMethod(app);
        end

        % Button pushed function: CoordinateButton
        function CoordinateButtonPushed(app, event)
            global image region_x region_y
            imshow(image);
            % let the user pick one point
            [x,y] = ginput(1);
            region_x = round(x);
            region_y = round(y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HMW3Tab = uitab(app.TabGroup);
            app.HMW3Tab.Title = 'HMW-3';
            app.EditField = uieditfield(app.HMW3Tab, 'text');
            app.EditField.Value = 'ORIGINAL IMAGE';
            app.EditField_2 = uieditfield(app.HMW3Tab, 'text');
            app.EditField_2.Value = 'IMAGE';
            app.EQUALIZATIONButton = uibutton(app.HMW3Tab, 'push');
            app.EQUALIZATIONButton.ButtonPushedFcn = createCallbackFcn(app, @EQUALIZATIONButtonPushed, true);
            app.EQUALIZATIONButton.Text = 'EQUALIZATION';
            app.STRECTHEDButton = uibutton(app.HMW3Tab, 'push');
            app.STRECTHEDButton.ButtonPushedFcn = createCallbackFcn(app, @STRECTHEDButtonPushed, true);
            app.STRECTHEDButton.Text = 'STRECTHED';
            app.SELECTIMAGEButton = uibutton(app.HMW3Tab, 'push');
            app.SELECTIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTIMAGEButtonPushed, true);
            app.SELECTIMAGEButton.Text = 'SELECT IMAGE';
            app.UIAxes = uiaxes(app.HMW3Tab);
            title(app.UIAxes, {''; ''})
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.HMW3Tab);
            title(app.UIAxes2, {''; ''})
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.UIAxes_2 = uiaxes(app.HistogramTab);
            title(app.UIAxes_2, 'Histogram')
            xlabel(app.UIAxes_2, 'Intensity')
            ylabel(app.UIAxes_2, 'Count')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.HistogramTab);
            title(app.UIAxes_3, 'Histogram')
            xlabel(app.UIAxes_3, 'Intensity')
            ylabel(app.UIAxes_3, 'Count')
            zlabel(app.UIAxes_3, 'Z')
            app.HMW41Tab = uitab(app.TabGroup);
            app.HMW41Tab.Title = 'HMW-4.1';
            app.SELECTIMAGEButton_2 = uibutton(app.HMW41Tab, 'push');
            app.SELECTIMAGEButton_2.ButtonPushedFcn = createCallbackFcn(app, @SELECTIMAGEButton_2Pushed, true);
            app.SELECTIMAGEButton_2.Text = 'SELECT IMAGE';
            app.MinimumFilterButton = uibutton(app.HMW41Tab, 'push');
            app.MinimumFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MinimumFilterButtonPushed, true);
            app.MinimumFilterButton.Text = 'Minimum Filter';
            app.MedianFilterButton = uibutton(app.HMW41Tab, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.MaximumFilterButton = uibutton(app.HMW41Tab, 'push');
            app.MaximumFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MaximumFilterButtonPushed, true);
            app.MaximumFilterButton.Text = 'Maximum Filter';
            app.AverageFilterButton = uibutton(app.HMW41Tab, 'push');
            app.AverageFilterButton.ButtonPushedFcn = createCallbackFcn(app, @AverageFilterButtonPushed, true);
            app.AverageFilterButton.Text = 'Average Filter';
            app.RowValueEditFieldLabel = uilabel(app.HMW41Tab);
            app.RowValueEditFieldLabel.Text = 'Row Value:';
            app.RowValueEditField = uieditfield(app.HMW41Tab, 'numeric');
            app.ColumnValueEditFieldLabel = uilabel(app.HMW41Tab);
            app.ColumnValueEditFieldLabel.Text = 'Column Value:';
            app.ColumnValueEditField = uieditfield(app.HMW41Tab, 'numeric');
            app.UIAxes_4 = uiaxes(app.HMW41Tab);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.Visible = 'off';
            app.UIAxes_5 = uiaxes(app.HMW41Tab);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.Visible = 'off';
            app.HMW42Tab = uitab(app.TabGroup);
            app.HMW42Tab.Title = 'HMW-4.2';
            app.SELECTIMAGEButton_3 = uibutton(app.HMW42Tab, 'push');
            app.SELECTIMAGEButton_3.ButtonPushedFcn = createCallbackFcn(app, @SELECTIMAGEButton_3Pushed, true);
            app.SELECTIMAGEButton_3.Text = 'SELECT IMAGE';
            app.LaplacianButton = uibutton(app.HMW42Tab, 'push');
            app.LaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianButtonPushed, true);
            app.LaplacianButton.Text = 'Laplacian';
            app.SobelButton = uibutton(app.HMW42Tab, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.SharpenedImageButton = uibutton(app.HMW42Tab, 'push');
            app.SharpenedImageButton.ButtonPushedFcn = createCallbackFcn(app, @SharpenedImageButtonPushed, true);
            app.SharpenedImageButton.Text = 'Sharpened Image';
            app.SobelSmoothButton = uibutton(app.HMW42Tab, 'push');
            app.SobelSmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SobelSmoothButtonPushed, true);
            app.SobelSmoothButton.Text = 'Sobel Smooth';
            app.MaskImageButton = uibutton(app.HMW42Tab, 'push');
            app.MaskImageButton.ButtonPushedFcn = createCallbackFcn(app, @MaskImageButtonPushed, true);
            app.MaskImageButton.Text = 'Mask Image';
            app.ImageMaskButton = uibutton(app.HMW42Tab, 'push');
            app.ImageMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ImageMaskButtonPushed, true);
            app.ImageMaskButton.Text = 'Image+Mask';
            app.PowerLowButton = uibutton(app.HMW42Tab, 'push');
            app.PowerLowButton.ButtonPushedFcn = createCallbackFcn(app, @PowerLowButtonPushed, true);
            app.PowerLowButton.Text = 'Power-Low';
            app.UIAxes3 = uiaxes(app.HMW42Tab);
            title(app.UIAxes3, {''; ''})
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3_2 = uiaxes(app.HMW42Tab);
            title(app.UIAxes3_2, {''; ''})
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_2.XTick = [];
            app.UIAxes3_2.YTick = [];
            app.HMW51Tab = uitab(app.TabGroup);
            app.HMW51Tab.SizeChangedFcn = createCallbackFcn(app, @HMW51TabSizeChanged, true);
            app.HMW51Tab.Title = 'HMW-5.1';
            app.SELECTIMAGEButton_4 = uibutton(app.HMW51Tab, 'push');
            app.SELECTIMAGEButton_4.ButtonPushedFcn = createCallbackFcn(app, @SELECTIMAGEButton_4Pushed, true);
            app.SELECTIMAGEButton_4.Text = 'SELECT IMAGE';
            app.HighPassFilterButton = uibutton(app.HMW51Tab, 'push');
            app.HighPassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @HighPassFilterButtonPushed, true);
            app.HighPassFilterButton.Text = 'High Pass Filter';
            app.ThresholdingButton = uibutton(app.HMW51Tab, 'push');
            app.ThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdingButtonPushed, true);
            app.ThresholdingButton.Text = 'Thresholding';
            app.SelectedImageLabel = uilabel(app.HMW51Tab);
            app.SelectedImageLabel.Text = 'Selected Image';
            app.FilteredImageLabel = uilabel(app.HMW51Tab);
            app.FilteredImageLabel.Text = 'Filtered Image';
            app.ThresholdedImageLabel = uilabel(app.HMW51Tab);
            app.ThresholdedImageLabel.Text = 'Thresholded Image';
            app.UIAxes_6 = uiaxes(app.HMW51Tab);
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes_6.Visible = 'off';
            app.UIAxes_7 = uiaxes(app.HMW51Tab);
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_7.Visible = 'off';
            app.UIAxes_8 = uiaxes(app.HMW51Tab);
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.UIAxes_8.Visible = 'off';
            app.HMW52Tab = uitab(app.TabGroup);
            app.HMW52Tab.Title = 'HMW-5.2';
            app.SELECTIMAGEButton_5 = uibutton(app.HMW52Tab, 'push');
            app.SELECTIMAGEButton_5.ButtonPushedFcn = createCallbackFcn(app, @SELECTIMAGEButton_5Pushed, true);
            app.SELECTIMAGEButton_5.Text = 'SELECT IMAGE';
            app.SelectedImageLabel_2 = uilabel(app.HMW52Tab);
            app.SelectedImageLabel_2.Text = '           Selected Image';
            app.MoirPatternButton = uibutton(app.HMW52Tab, 'push');
            app.MoirPatternButton.ButtonPushedFcn = createCallbackFcn(app, @MoirPatternButtonPushed, true);
            app.MoirPatternButton.Text = 'Moiré  Pattern';
            app.FilteredImageLabel_2 = uilabel(app.HMW52Tab);
            app.FilteredImageLabel_2.Text = '            Filtered Image';
            app.NotchRejectFilterLabel = uilabel(app.HMW52Tab);
            app.NotchRejectFilterLabel.Text = '            Notch Reject Filter';
            app.UIAxes_9 = uiaxes(app.HMW52Tab);
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_9.Visible = 'off';
            app.UIAxes_10 = uiaxes(app.HMW52Tab);
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.UIAxes_10.Visible = 'off';
            app.UIAxes_11 = uiaxes(app.HMW52Tab);
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.UIAxes_11.Visible = 'off';
            app.HMW6Tab = uitab(app.TabGroup);
            app.HMW6Tab.Title = 'HMW-6';
            app.SelectedImageLabel_3 = uilabel(app.HMW6Tab);
            app.SelectedImageLabel_3.Text = '           Selected Image';
            app.Label = uilabel(app.HMW6Tab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.HMW6Tab);
            app.Label_2.Text = '';
            app.SELECTIMAGEButton_6 = uibutton(app.HMW6Tab, 'push');
            app.SELECTIMAGEButton_6.ButtonPushedFcn = createCallbackFcn(app, @SELECTIMAGEButton_6Pushed, true);
            app.SELECTIMAGEButton_6.Text = 'SELECT IMAGE';
            app.DropDown = uidropdown(app.HMW6Tab);
            app.DropDown.Items = {'CHOOSE NOISE', 'SALT NOISE', 'PEPPER NOISE', 'SALT&PEPPER NOISE', 'PERIODIC NOISE', 'GAUSSIAN NOISE', 'BLUR IMAGE'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged2, true);
            app.DropDown.Value = 'CHOOSE NOISE';
            app.EditField_3 = uieditfield(app.HMW6Tab, 'numeric');
            app.EditField_4 = uieditfield(app.HMW6Tab, 'numeric');
            app.APPLYNOISEButton = uibutton(app.HMW6Tab, 'push');
            app.APPLYNOISEButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYNOISEButtonPushed, true);
            app.APPLYNOISEButton.Text = 'APPLY NOISE';
            app.APPLYFILTERButton = uibutton(app.HMW6Tab, 'push');
            app.APPLYFILTERButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYFILTERButtonPushed, true);
            app.APPLYFILTERButton.Text = 'APPLY FILTER';
            app.EditField_5 = uieditfield(app.HMW6Tab, 'text');
            app.EditField_6 = uieditfield(app.HMW6Tab, 'text');
            app.DropDown_2 = uidropdown(app.HMW6Tab);
            app.DropDown_2.Items = {'CHOOSE FILTER', 'MEDIAN FILTER', 'MAX FILTER', 'MIN FILTER', 'AVERAGE FILTER', 'WEINER FILTER', 'ADAPTIVE LOCAL FILTER', 'NOTCH FILTER'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'CHOOSE FILTER';
            app.UIAxes_12 = uiaxes(app.HMW6Tab);
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.UIAxes_12.Visible = 'off';
            app.UIAxes_13 = uiaxes(app.HMW6Tab);
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.YTick = [];
            app.UIAxes_13.Visible = 'off';
            app.UIAxes_14 = uiaxes(app.HMW6Tab);
            app.UIAxes_14.XTick = [];
            app.UIAxes_14.YTick = [];
            app.UIAxes_14.Visible = 'off';
            app.UIAxes_14.ButtonDownFcn = createCallbackFcn(app, @UIAxes_14ButtonDown, true);
            app.HMW7Tab = uitab(app.TabGroup);
            app.HMW7Tab.Title = 'HMW-7';
            app.ORIGINALIMAGELabel = uilabel(app.HMW7Tab);
            app.ORIGINALIMAGELabel.Text = '           ORIGINAL IMAGE';
            app.SELECTIMAGEButton_7 = uibutton(app.HMW7Tab, 'push');
            app.SELECTIMAGEButton_7.ButtonPushedFcn = createCallbackFcn(app, @SELECTIMAGEButton_7Pushed, true);
            app.SELECTIMAGEButton_7.Text = 'SELECT IMAGE';
            app.Label_3 = uilabel(app.HMW7Tab);
            app.Label_3.Text = '';
            app.DropDown_3 = uidropdown(app.HMW7Tab);
            app.DropDown_3.Items = {'CHOOSE FILTER', 'MEDIAN FILTER', 'WIENER FILTER'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'CHOOSE FILTER';
            app.EditField_7 = uieditfield(app.HMW7Tab, 'numeric');
            app.EditField_8 = uieditfield(app.HMW7Tab, 'text');
            app.EditField_9 = uieditfield(app.HMW7Tab, 'numeric');
            app.EditField_10 = uieditfield(app.HMW7Tab, 'text');
            app.APPLYFILTERButton_2 = uibutton(app.HMW7Tab, 'push');
            app.APPLYFILTERButton_2.ButtonPushedFcn = createCallbackFcn(app, @APPLYFILTERButton_2Pushed, true);
            app.APPLYFILTERButton_2.Text = 'APPLY FILTER';
            app.UIAxes_15 = uiaxes(app.HMW7Tab);
            app.UIAxes_15.XTick = [];
            app.UIAxes_15.YTick = [];
            app.UIAxes_15.Visible = 'off';
            app.UIAxes_16 = uiaxes(app.HMW7Tab);
            app.UIAxes_16.XTick = [];
            app.UIAxes_16.YTick = [];
            app.UIAxes_16.Visible = 'off';
            app.HMW8Tab = uitab(app.TabGroup);
            app.HMW8Tab.Title = 'HMW-8';
            app.ORIGINALIMAGELabel_2 = uilabel(app.HMW8Tab);
            app.ORIGINALIMAGELabel_2.Text = '           ORIGINAL IMAGE';
            app.SELECTIMAGEButton_8 = uibutton(app.HMW8Tab, 'push');
            app.SELECTIMAGEButton_8.ButtonPushedFcn = createCallbackFcn(app, @SELECTIMAGEButton_8Pushed, true);
            app.SELECTIMAGEButton_8.Text = 'SELECT IMAGE';
            app.Label_4 = uilabel(app.HMW8Tab);
            app.Label_4.Text = '';
            app.DropDown_4 = uidropdown(app.HMW8Tab);
            app.DropDown_4.Items = {'CHOOSE FILTER', 'OPEN IMAGE', 'CLOSE IMAGE', 'EXTRACT GRADIENT', 'TOP HAT TRANSFORMATION', 'TEXTUAL SEGMENTATION'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = 'CHOOSE FILTER';
            app.APPLYFILTERButton_3 = uibutton(app.HMW8Tab, 'push');
            app.APPLYFILTERButton_3.ButtonPushedFcn = createCallbackFcn(app, @APPLYFILTERButton_3Pushed, true);
            app.APPLYFILTERButton_3.Text = 'APPLY FILTER';
            app.EditField_11 = uieditfield(app.HMW8Tab, 'numeric');
            app.EditField_12 = uieditfield(app.HMW8Tab, 'text');
            app.EditField_13 = uieditfield(app.HMW8Tab, 'numeric');
            app.EditField_14 = uieditfield(app.HMW8Tab, 'text');
            app.DropDown_5 = uidropdown(app.HMW8Tab);
            app.DropDown_5.Items = {'disk', 'sphere', 'diamond', 'cube'};
            app.DropDown_5.Value = 'disk';
            app.UIAxes_17 = uiaxes(app.HMW8Tab);
            app.UIAxes_17.XTick = [];
            app.UIAxes_17.YTick = [];
            app.UIAxes_17.Visible = 'off';
            app.UIAxes_18 = uiaxes(app.HMW8Tab);
            app.UIAxes_18.XTick = [];
            app.UIAxes_18.YTick = [];
            app.UIAxes_18.Visible = 'off';
            app.HMW9Tab = uitab(app.TabGroup);
            app.HMW9Tab.Title = 'HMW-9';
            app.ORIGINALIMAGELabel_3 = uilabel(app.HMW9Tab);
            app.ORIGINALIMAGELabel_3.Text = '           ORIGINAL IMAGE';
            app.Label_5 = uilabel(app.HMW9Tab);
            app.Label_5.Text = '';
            app.SELECTIMAGEButton_9 = uibutton(app.HMW9Tab, 'push');
            app.SELECTIMAGEButton_9.ButtonPushedFcn = createCallbackFcn(app, @SELECTIMAGEButton_9Pushed, true);
            app.SELECTIMAGEButton_9.Text = 'SELECT IMAGE';
            app.OTSUMETHODButton = uibutton(app.HMW9Tab, 'push');
            app.OTSUMETHODButton.ButtonPushedFcn = createCallbackFcn(app, @OTSUMETHODButtonPushed, true);
            app.OTSUMETHODButton.Text = 'OTSU METHOD';
            app.RegionGrowingButton = uibutton(app.HMW9Tab, 'push');
            app.RegionGrowingButton.ButtonPushedFcn = createCallbackFcn(app, @RegionGrowingButtonPushed, true);
            app.RegionGrowingButton.Text = 'Region Growing';
            app.CoordinateButton = uibutton(app.HMW9Tab, 'push');
            app.CoordinateButton.ButtonPushedFcn = createCallbackFcn(app, @CoordinateButtonPushed, true);
            app.CoordinateButton.Text = 'Coordinate';
            app.UIAxes_19 = uiaxes(app.HMW9Tab);
            app.UIAxes_19.XTick = [];
            app.UIAxes_19.YTick = [];
            app.UIAxes_19.Visible = 'off';
            app.UIAxes_20 = uiaxes(app.HMW9Tab);
            app.UIAxes_20.XTick = [];
            app.UIAxes_20.YTick = [];
            app.UIAxes_20.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end